classdef AppFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CargarSegunaImagenButton        matlab.ui.control.Button
        DescargarButton                 matlab.ui.control.Button
        ReiniciarEdicionButton          matlab.ui.control.Button
        CargarImagenButton              matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        EditField                       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        MejoraImagenButton              matlab.ui.control.Button
        SepararComponentesButton        matlab.ui.control.Button
        BInarizacionButton              matlab.ui.control.Button
        ProcesamientoBasicoLabel        matlab.ui.control.Label
        EscaladeGrisesButton            matlab.ui.control.Button
        Tab2                            matlab.ui.container.Tab
        CalcularLCButton                matlab.ui.control.Button
        CalcularLogicaButton            matlab.ui.control.Button
        CalcularGeometricasButton       matlab.ui.control.Button
        TraslacionLabel                 matlab.ui.control.Label
        YEditField                      matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        XEditField                      matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        EscalamientoEditField           matlab.ui.control.NumericEditField
        EscalamientoEditFieldLabel      matlab.ui.control.Label
        RotacinEditFieldLabel           matlab.ui.control.Label
        RotacinEditField                matlab.ui.control.NumericEditField
        CalcularAritmeticaButton        matlab.ui.control.Button
        ValorConstanteEditField         matlab.ui.control.NumericEditField
        ValorConstanteLabel             matlab.ui.control.Label
        Pixel2EditField                 matlab.ui.control.NumericEditField
        Pixel2EditFieldLabel            matlab.ui.control.Label
        LogicasCompuestasDropDown       matlab.ui.control.DropDown
        LogicasCompuestasDropDownLabel  matlab.ui.control.Label
        LogicasDropDown                 matlab.ui.control.DropDown
        LogicasDropDownLabel            matlab.ui.control.Label
        GeometricasDropDown             matlab.ui.control.DropDown
        GeometricasDropDownLabel        matlab.ui.control.Label
        AritmeticasDropDown             matlab.ui.control.DropDown
        AritmeticasDropDownLabel        matlab.ui.control.Label
        MostrarVecinosButton            matlab.ui.control.Button
        CalcularDIstanciaButton         matlab.ui.control.Button
        VecindadDropDown                matlab.ui.control.DropDown
        VecindadDropDownLabel           matlab.ui.control.Label
        Pixel1EditField                 matlab.ui.control.NumericEditField
        Pixel1EditFieldLabel            matlab.ui.control.Label
        DistanciaDropDown               matlab.ui.control.DropDown
        DistanciaDropDownLabel          matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        AplicarFiltroButton             matlab.ui.control.Button
        FiltroDropDown                  matlab.ui.control.DropDown
        FiltroDropDownLabel             matlab.ui.control.Label
        AplicarRuidoButton              matlab.ui.control.Button
        IntensidadDropDownLabel         matlab.ui.control.Label
        IntensidadDropDown              matlab.ui.control.DropDown
        RuidoDropDown                   matlab.ui.control.DropDown
        RuidoDropDownLabel              matlab.ui.control.Label
        RuidoyFIltrosLabel              matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes3_3                       matlab.ui.control.UIAxes
        UIAxes3_2                       matlab.ui.control.UIAxes
        UIAxes3                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
     properties (Access = private)
        ImagenOriginal      % Almacena la matriz de la imagen cargada
        ImagenProcesada     % Almacena la matriz en proceso actual
        ImagenConRuido      % Almacena la matriz contaminada con ruido
        ImagenGris
        X_size              % Almacena el valor de las filas de una imagen
        Y_size              % Almacena el valor de las columnas de una imagen
        ImagenOriginal2     % Almacena la segunda fotografia
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EscaladeGrisesButton
        function EscaladeGrisesButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero debes cargar una imagen.', 'Advertencia');
                return;
            end
            % Mandar a llamar a la funcion manual de Grises
            app.ImagenProcesada = obtenerGrisManual(app.ImagenOriginal);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
            [archivo, ruta] = uigetfile({'*.jpg;*.png;*.bmp','Archivos de Imagen'});
            if archivo ~= 0
                rutaCompleta = fullfile(ruta, archivo);
                app.ImagenOriginal = imread(rutaCompleta);
                app.ImagenProcesada = app.ImagenOriginal; 
                imshow(app.ImagenOriginal, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: BInarizacionButton
        function BInarizacionButtonPushed(app, event)
            if isempty(app.ImagenProcesada)
                uialert(app.UIFigure, 'No hay imagen procesada para binarizar.', 'Advertencia');
                return;
            end

            imgActual = app.ImagenProcesada;
            if size(imgActual, 3) == 3
                imgActual = obtenerGrisManual(imgActual);
            end

            % Leer el umbral del campo de texto numérico
            umbralUsuario = app.EditField.Value;

            % Mandar a llamar tu función independiente de umbral/binario
            app.ImagenProcesada = binarizarManual(imgActual, umbralUsuario);
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: SepararComponentesButton
        function SepararComponentesButtonPushed(app, event)
            % 1. Verificar que ya exista una imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Por favor carga una imagen primero.', 'Advertencia');
                return;
            end

            % 2. Verificar que la imagen sea a color (tenga 3 canales)
            % Usamos un try/catch o condicional manual para evitar que falle con imágenes en grises
            try
                pruebaCanal = app.ImagenOriginal(1,1,3); % Si no hay canal 3, saltará al catch
            catch
                uialert(app.UIFigure, 'La imagen cargada no tiene colores RGB para separar.', 'Aviso');
                return;
            end

            % 3. Extraer los colores usando tu función artesanal
            [ImgRed, ImgGreen, ImgBlue] = ExtraerColores(app.ImagenOriginal, app.Y_size, app.X_size);

            % 4. Enviar las matrices a tus 3 Axes "multiusos"
            imshow(ImgRed, 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Canal Rojo (R)'); % Le ponemos título para que se vea pro

            % OJO: Respeté tu escritura "UIAxess3_2" (con doble s), verifica que así se llame exactamente en tu App Designer
            imshow(ImgGreen, 'Parent', app.UIAxes3_2);
            title(app.UIAxes3_2, 'Canal Verde (G)');

            imshow(ImgBlue, 'Parent', app.UIAxes3_3);
            title(app.UIAxes3_3, 'Canal Azul (B)');
        end

        % Button pushed function: MejoraImagenButton
        function MejoraImagenButtonPushed(app, event)
            % 1. Verificar que exista una imagen cargada
            if isempty(app.UIAxes)
                uialert(app.UIFigure, 'Por favor, carga una imagen primero.', 'Advertencia');
                return;
            end
            
            % 2. Asegurarnos de que la imagen base esté en escala de grises
            imgActual = app.UIAxes;
            if size(imgActual, 3) == 3
                imgActual = obtenerGrisManual(imgActual);
            end
            
            % 3. Mandar a llamar tu grandiosa función artesanal
            [imgEcualizada, H_original, H_ecualizado] = ecualizarAmano(imgActual);
            
            % 4. Mostrar las imágenes
            % OJO: Si tus ejes se llaman diferente en App Designer, cambia "UIAxesOriginal" y "UIAxesProcesada"
            cla(app.UIAxes);
            cla(app.UIAxes_2);
            
            imshow(imgActual, 'Parent', app.UIAxes);
            title(app.UIAxes, 'Imagen en Grises');
            
            imshow(imgEcualizada, 'Parent', app.UIAxes_2);
            title(app.UIAxesProcesada, 'Imagen Ecualizada');
            
            % 5. Mostrar los histogramas en los Axes multiusos (Punto 5 de la rúbrica)
            
            % Histograma Original
            bar(app.UIAxes3, 0:255, H_original, 'FaceColor', [0.2 0.6 0.8], 'EdgeColor', 'none');
            title(app.UIAxes3, 'Histograma Original');
            xlim(app.UIAxes3, [0 255]); 
            
            % Histograma Ecualizado (CORREGIDO: Le quité la doble 's' para que no falle)
            bar(app.UIAxes3_2, 0:255, H_ecualizado, 'FaceColor', [0.8 0.2 0.2], 'EdgeColor', 'none');
            title(app.UIAxes3_2, 'Histograma Ecualizado');
            xlim(app.UIAxes3_2, [0 255]);
            
            % Limpiamos el tercer Axes multiusos por si había algo ahí
            cla(app.UIAxes3_3);
            title(app.UIAxes3_3, '');
        end

        % Button pushed function: CalcularDIstanciaButton
        function CalcularDIstanciaButtonPushed(app, event)
            % 1. Verificar que haya imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error de validación');
                return;
            end

            % 2. POP-UP Y CLIC PARA EL PRIMER PUNTO
            uialert(app.UIFigure, 'Haz clic en "OK" y luego selecciona el PRIMER PUNTO (origen) directamente sobre la foto.', 'Punto 1');

            % ginput(1) pausa la app y captura las coordenadas exactas de 1 solo clic
            [x1, y1] = ginput(1);
            P1 = [round(x1), round(y1)];

            % PINTAR EL PUNTO 1 (Estrella gigante Magenta con centro Amarillo)
            hold(app.UIAxes, 'on'); % Bloqueamos la imagen para pintar encima sin borrarla
            plot(app.UIAxes, P1(1), P1(2), 'p', 'MarkerSize', 20, 'MarkerEdgeColor', 'm', 'MarkerFaceColor', 'y', 'LineWidth', 2);

            % 3. POP-UP Y CLIC PARA EL SEGUNDO PUNTO
            uialert(app.UIFigure, 'Ahora haz clic en "OK" y selecciona el SEGUNDO PUNTO (destino).', 'Punto 2');

            [x2, y2] = ginput(1);
            P2 = [round(x2), round(y2)];

            % PINTAR EL PUNTO 2 (Estrella gigante Cian con centro Amarillo)
            plot(app.UIAxes, P2(1), P2(2), 'p', 'MarkerSize', 20, 'MarkerEdgeColor', 'c', 'MarkerFaceColor', 'y', 'LineWidth', 2);

            % (Opcional visual) Trazar una línea verde neón brillante que conecte los dos puntos
            plot(app.UIAxes, [P1(1), P2(1)], [P1(2), P2(2)], '-', 'Color', 'g', 'LineWidth', 2);
            hold(app.UIAxes, 'off'); % Soltamos la imagen

            % 4. Revisamos que los clics no se salgan de las dimensiones reales de la matriz
            if P1(1) < 1 || P1(1) > app.X_size || P1(2) < 1 || P1(2) > app.Y_size || ...
                    P2(1) < 1 || P2(1) > app.X_size || P2(2) < 1 || P2(2) > app.Y_size

                app.LabelResultado.Text = 'Error: Diste clic fuera de la imagen :P';
                return;
            end

            % 5. Leemos qué distancia seleccionó en el DropDown
            tipoDistancia = app.DropDownDistancias.Value;

            % Calculamos la distancia usando las bibliotecas de tu compañero
            if strcmp(tipoDistancia, 'Euclidiana')
                d = distEuclidiana(P1, P2);
            elseif strcmp(tipoDistancia, 'Manhattan')
                d = distManhattan(P1, P2);
            elseif strcmp(tipoDistancia, 'Ajedrez') 
                d = distAjedrez(P1, P2);
            end

            % 6. Imprimimos el resultado final en la etiqueta
            app.LabelResultado.Text = sprintf('Distancia %s: %.2f píxeles', tipoDistancia, d);
        end

        % Button pushed function: MostrarVecinosButton
        function MostrarVecinosButtonPushed(app, event)
            % 1. Validar que la imagen original exista
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Carga una imagen primero.', 'Error de validación');
                return;
            end

            % 2. Por seguridad, si tu variable ImagenGris está vacía, la calculamos con TU función manual
            if isempty(app.ImagenGris)
                app.ImagenGris = obtenerGrisManual(app.ImagenOriginal);
            end

            % 3. POP-UP DE ENFOQUE Y CAPTURA DEL CLIC
            uialert(app.UIFigure, 'Haz clic en "OK" y luego selecciona el PÍXEL CENTRAL directamente sobre la foto.', 'Seleccionar Píxel');

            % ginput(1) captura las coordenadas exactas (Columna X, Fila Y)
            [x, y] = ginput(1);
            Px = round(x);
            Py = round(y);

            % 4. DIBUJAR MARCADOR DE ALTO CONTRASTE (Cruz gigante Verde Neón con centro Magenta)
            hold(app.UIAxes, 'on');
            % Pintamos una cruz 'x' grande
            plot(app.UIAxes, Px, Py, 'x', 'MarkerSize', 25, 'MarkerEdgeColor', 'g', 'LineWidth', 3);
            % Pintamos un punto '.' en el centro exacto
            plot(app.UIAxes, Px, Py, '.', 'MarkerSize', 15, 'MarkerEdgeColor', 'm');
            hold(app.UIAxes, 'off');

            % 5. Validar límites de la matriz utilizando tus propiedades registradas
            if Px < 1 || Px > app.X_size || Py < 1 || Py > app.Y_size
                app.LabelResultado.Text = 'Error: Clic fuera de la imagen.';
                uialert(app.UIFigure, 'El clic se encuentra fuera de los límites reales de la matriz.', 'Error de Coordenadas');
                return;
            end

            % 6. Leer qué vecindad seleccionó en el DropDown
            tipoVecindad = app.DropDownVecinos.Value;
            vecinos = [];

            % 7. Calcular los vecinos llamando a tus librerías personalizadas de prácticas anteriores
            % Pasamos las dimensiones guardadas en la app por si tus funciones las requieren
            if strcmp(tipoVecindad, '8-Vecinos')
                vecinos = obtenerVecindad8(app.ImagenGris, Px, Py, app.X_size, app.Y_size);
            elseif strcmp(tipoVecindad, '4-Vecinos')
                vecinos = obtenerVecindad4(app.ImagenGris, Px, Py, app.X_size, app.Y_size);
            elseif strcmp(tipoVecindad, 'D-Vecinos')
                vecinos = obtenerVecindadD(app.ImagenGris, Px, Py, app.X_size, app.Y_size);
            end

            % 8. Convertir el arreglo numérico resultante a texto y mostrarlo en la interfaz
            textoMatriz = mat2str(vecinos);
            app.LabelResultado.Text = sprintf('%s en (%d,%d): %s', tipoVecindad, Px, Py, textoMatriz);
        end

        % Button pushed function: CalcularAritmeticaButton
        function CalcularAritmeticaButtonPushed(app, event)
            % 1. Validar que exista la imagen base
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
                return;
            end
            
            % 2. Leer valores de la Interfaz
            C = app.ValorConstanteEditField.Value;
            tipoOperacion = app.AritmeticasDropDown.Value;
            
            [filas, columnas, canales] = size(app.ImagenOriginal);
            imgResultado = zeros(filas, columnas, canales, 'uint8');
            
            % 3. Ejecutar la operación correspondiente
            switch tipoOperacion
                % --- OPERACIONES CON ESCALAR ---
                case 'Suma Escalar'
                    imgResultado = sumaEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    
                case 'Resta Escalar'
                    imgResultado = restaEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    
                case 'Producto Escalar'
                    imgResultado = productoEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    
                case 'Division Escalar'
                    if C == 0
                        uialert(app.UIFigure, 'Error matemático: No puedes dividir la imagen entre 0.', 'Aviso');
                        return;
                    end
                    imgResultado = divisionEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
        
                % --- OPERACIONES ENTRE IMÁGENES ---
                case 'Suma Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                    imgResultado = sumaImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
                    
                case 'Resta de Imagenes reales' % Revisa no tener espacios al final en tu DropDown
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                    imgResultado = restaImagenesReal(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
        
                case 'Resta de Imagenes absoluta'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                    imgResultado = restaImagenesAbsoluta(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
        
                case 'Producto Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                    imgResultado = productoImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
        
                case 'Division Imagenes'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Carga la SEGUNDA imagen primero.', 'Error');
                        return;
                    end
                    img2_ajustada = imresize(app.ImagenOriginal2, [app.Y_size, app.X_size]);
                    imgResultado = divisionImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
                    
                otherwise
                    uialert(app.UIFigure, 'Operación no válida.', 'Aviso');
                    return;
            end
            
            % 4. Mostrar el resultado en los ejes
            cla(app.OperacionAritmetica); % Limpiamos pantalla anterior
            imshow(imgResultado, 'Parent', app.OperacionAritmetica);
            title(app.OperacionAritmetica, tipoOperacion);
        end

        % Button pushed function: CalcularLogicaButton
        function CalcularLogicaButtonPushed(app, event)
            % 1. Validar que exista la primera imagen cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero debes cargar la imagen base.', 'Error de validación');
                return;
            end

            % 2. Leer los valores de la interfaz
            tipoOperacion = app.LogicasDropDown.Value;
            C = app.ValorConstanteEditField.Value; % Valor escalar constante

            [filas, columnas, canales] = size(app.ImagenOriginal);
            imgResultado = zeros(filas, columnas, canales, 'uint8');

            % 3. Evaluar la operación seleccionada en el Switch
            switch tipoOperacion
                % --- OPERACIONES CON ESCALAR CONSTANTE ---
                case 'AND Escalar'
                    resLogico = OperacionesLogicas.andEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    % Convertir de lógico (0 y 1) a visualización uint8 (0 y 255)
                    imgResultado = uint8(resLogico) * 255;

                case 'OR Escalar'
                    resLogico = OperacionesLogicas.orEscalar(app.ImagenOriginal, C, app.Y_size, app.X_size, canales);
                    imgResultado = uint8(resLogico) * 255;

                    % --- OPERACIONES ENTRE IMÁGENES ---    
                case 'AND'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Por favor, carga la SEGUNDA imagen primero.', 'Falta Imagen');
                        return;
                    end
                    % Redimensionar la segunda imagen a mano sin usar el Toolbox
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);
                    resLogico = OperacionesLogicas.andImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
                    imgResultado = uint8(resLogico) * 255;

                case 'OR'
                    if isempty(app.ImagenOriginal2)
                        uialert(app.UIFigure, 'Por favor, carga la SEGUNDA imagen primero.', 'Falta Imagen');
                        return;
                    end
                    img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);
                    resLogico = OperacionesLogicas.orImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);
                    imgResultado = uint8(resLogico) * 255;

                    % --- INVERSIÓN FOTOGRÁFICA (NOT) ---
                case 'NOT'
                    % Esta función ya devuelve uint8 directamente en su código base
                    imgResultado = OperacionesLogicas.notImagen(app.ImagenOriginal, app.Y_size, app.X_size, canales);

                otherwise
                    uialert(app.UIFigure, 'Operación lógica no reconocida.', 'Aviso');
                    return;
            end

            % 4. Mostrar el resultado final en la pantalla editada (UIAxes_2)
            cla(app.UIAxes_2); 
            imshow(imgResultado, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, sprintf('Resultado: %s', tipoOperacion));
        end

        % Button pushed function: CargarSegunaImagenButton
        function CargarSegunaImagenButtonPushed(app, event)
            % 1. Abrir explorador de archivos nativo
            [archivo, ruta] = uigetfile({'*.jpg;*.jpeg;*.png;*.bmp', 'Imágenes (*.jpg, *.jpeg, *.png, *.bmp)'}, ...
                'Selecciona la SEGUNDA imagen para operaciones compuestas');

            % Si el usuario cancela la selección, salimos pacíficamente
            if archivo == 0
                return;
            end

            % 2. Leer el archivo y almacenarlo en la propiedad global correcta
            rutaCompleta = fullfile(ruta, archivo);
            app.ImagenOriginal2 = imread(rutaCompleta);

            % 3. Limpiar y desplegar el contenido en el panel multiusos UIAxes3
            cla(app.UIAxes3); % Borra si antes había un histograma o canal de color
            imshow(app.ImagenOriginal2, 'Parent', app.UIAxes3);
            title(app.UIAxes3, 'Segunda Imagen Cargada');

            % Mensaje de confirmación en la consola para tus pruebas
            disp('Segunda imagen cargada con éxito en UIAxes3 y asignada a app.ImagenOriginal2.');
        end

        % Button pushed function: CalcularLCButton
        function CalcularLCButtonPushed(app, event)
            % 1. Validar que la primera imagen esté cargada
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Primero debes cargar la imagen base (Imagen 1).', 'Falta Imagen');
                return;
            end

            % 2. Validar que la segunda imagen esté cargada
            if isempty(app.ImagenOriginal2)
                uialert(app.UIFigure, 'Debes cargar la SEGUNDA imagen para realizar esta operación.', 'Falta Imagen');
                return;
            end

            % 3. Leer la operación seleccionada de la interfaz
            tipoOperacion = app.LogicasCompuestasDropDown.Value;

            % 4. Redimensionar la segunda imagen a mano (Cero Toolboxes)
            [filas, columnas, canales] = size(app.ImagenOriginal);
            img2_ajustada = redimensionarManual(app.ImagenOriginal2, app.Y_size, app.X_size);

            % 5. Evaluar la operación seleccionada llamando a tus métodos estáticos
            switch tipoOperacion
                case 'NAND'
                    resLogico = OperacionesLogicasCompuestas.nandImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);

                case 'NOR'
                    resLogico = OperacionesLogicasCompuestas.norImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);

                case 'XOR'
                    resLogico = OperacionesLogicasCompuestas.xorImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);

                case 'XNOR'
                    resLogico = OperacionesLogicasCompuestas.xnorImagenes(app.ImagenOriginal, img2_ajustada, app.Y_size, app.X_size, canales);

                otherwise
                    uialert(app.UIFigure, 'Operación no reconocida.', 'Aviso');
                    return;
            end

            % 6. Convertir de lógico (0, 1) a formato de imagen uint8 (0, 255)
            imgResultado = uint8(resLogico) * 255;

            % 7. Mostrar el resultado en la pantalla de edición (UIAxes_2)
            cla(app.UIAxes_2);
            imshow(imgResultado, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, sprintf('Resultado: %s', tipoOperacion));
        end

        % Button pushed function: AplicarRuidoButton
        function AplicarRuidoButtonPushed(app, event)
            if isempty(app.ImagenOriginal)
                uialert(app.UIFigure, 'Carga una imagen primero.', 'Advertencia');
                return;
            end

            tipoRuido = app.RuidoDropDown.Value;
            nivelStr = app.IntensidadDropDown.Value; 
            img = app.ImagenOriginal;

            % Traducir la opción del menú a un valor numérico del 1 al 5
            switch nivelStr
                case {'Nivel 1 - Muy bajo', 'Nivel 1'}
                    val = 1; 
                case {'Nivel 2', 'Nivel 2 - Medio'} 
                    val = 2;
                case {'Nivel 3', 'Nivel 3 - Alto'}
                    val = 3;
                case {'Nivel 4', 'Nivel 4 - Muy Alto'}
                    val = 4;
                case 'Nivel 5'
                    val = 5;
                otherwise
                    val = 1;
            end

            switch tipoRuido
                case 'Sal y Pimienta'
                    densidades = [0.01, 0.03, 0.05, 0.10, 0.20];
                    app.ImagenConRuido = ruidoSalPimienta(img, densidades(val));
                case 'Gaussiano'
                    varianzas = [0.005, 0.01, 0.02, 0.05, 0.10];
                    app.ImagenConRuido = ruidoGaussiano(img, 0, varianzas(val));
                case 'Uniforme'
                    limitesB = [0.05, 0.1, 0.2, 0.3, 0.5];
                    app.ImagenConRuido = ruidoUniforme(img, -limitesB(val), limitesB(val));
                case 'Impulso'
                    probabilidades = [0.02, 0.05, 0.10, 0.15, 0.25];
                    app.ImagenConRuido = ruidoImpulso(img, probabilidades(val));
            end

            app.ImagenProcesada = app.ImagenConRuido;
            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end

        % Button pushed function: AplicarFiltroButton
        function AplicarFiltroButtonPushed(app, event)
            if isempty(app.ImagenConRuido)
                uialert(app.UIFigure, 'Primero debes aplicar ruido a la imagen.', 'Advertencia');
                return;
            end

            tipoFiltro = app.FiltroDropDown.Value;
            imgRuido = app.ImagenConRuido;

            switch tipoFiltro
                case 'Promedio'
                    app.ImagenProcesada = filtroPromedioManual(imgRuido, 3);
                case 'Mediana'
                    app.ImagenProcesada = filtroMedianaManual(imgRuido, 3);
                case 'Gaussiano'
                    app.ImagenProcesada = filtroGaussianoManual(imgRuido);
                case {'Bilateral', 'BIlaterial'} % Cubriendo el pequeño detalle de dedo en tu menú
                    app.ImagenProcesada = filtroBilateralManual(imgRuido, 2.0, 30.0);
            end

            imshow(app.ImagenProcesada, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Imagen Original')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Edicion')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.EscaladeGrisesButton = uibutton(app.Tab, 'push');
            app.EscaladeGrisesButton.ButtonPushedFcn = createCallbackFcn(app, @EscaladeGrisesButtonPushed, true);
            app.EscaladeGrisesButton.Text = 'Escala de Grises';
            app.ProcesamientoBasicoLabel = uilabel(app.Tab);
            app.ProcesamientoBasicoLabel.Text = 'Procesamiento Basico';
            app.BInarizacionButton = uibutton(app.Tab, 'push');
            app.BInarizacionButton.ButtonPushedFcn = createCallbackFcn(app, @BInarizacionButtonPushed, true);
            app.BInarizacionButton.Text = 'BInarizacion';
            app.SepararComponentesButton = uibutton(app.Tab, 'push');
            app.SepararComponentesButton.ButtonPushedFcn = createCallbackFcn(app, @SepararComponentesButtonPushed, true);
            app.SepararComponentesButton.Text = {'Separar Componentes '; 'De Color'};
            app.MejoraImagenButton = uibutton(app.Tab, 'push');
            app.MejoraImagenButton.ButtonPushedFcn = createCallbackFcn(app, @MejoraImagenButtonPushed, true);
            app.MejoraImagenButton.Text = 'Mejora De Imagen';
            app.EditFieldLabel = uilabel(app.Tab);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.Tab, 'numeric');
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Label = uilabel(app.Tab2);
            app.Label.Text = '';
            app.DistanciaDropDownLabel = uilabel(app.Tab2);
            app.DistanciaDropDownLabel.Text = 'Distancia';
            app.DistanciaDropDown = uidropdown(app.Tab2);
            app.DistanciaDropDown.Items = {'Euclidiana', 'Manhattan', 'Ajedrez'};
            app.DistanciaDropDown.Value = 'Euclidiana';
            app.Pixel1EditFieldLabel = uilabel(app.Tab2);
            app.Pixel1EditFieldLabel.Text = 'Pixel 1';
            app.Pixel1EditField = uieditfield(app.Tab2, 'numeric');
            app.VecindadDropDownLabel = uilabel(app.Tab2);
            app.VecindadDropDownLabel.Text = 'Vecindad';
            app.VecindadDropDown = uidropdown(app.Tab2);
            app.VecindadDropDown.Items = {'4', '8', 'D'};
            app.VecindadDropDown.Value = '4';
            app.CalcularDIstanciaButton = uibutton(app.Tab2, 'push');
            app.CalcularDIstanciaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularDIstanciaButtonPushed, true);
            app.CalcularDIstanciaButton.Text = 'Calcular DIstancia';
            app.MostrarVecinosButton = uibutton(app.Tab2, 'push');
            app.MostrarVecinosButton.ButtonPushedFcn = createCallbackFcn(app, @MostrarVecinosButtonPushed, true);
            app.MostrarVecinosButton.Text = 'Mostrar Vecinos';
            app.AritmeticasDropDownLabel = uilabel(app.Tab2);
            app.AritmeticasDropDownLabel.Text = 'Aritmeticas';
            app.AritmeticasDropDown = uidropdown(app.Tab2);
            app.AritmeticasDropDown.Items = {'Suma Escalar', 'Suma Imagenes', 'Resta Escalar', 'Resta de '};
            app.AritmeticasDropDown.Value = 'Suma Escalar';
            app.GeometricasDropDownLabel = uilabel(app.Tab2);
            app.GeometricasDropDownLabel.Text = 'Geometricas';
            app.GeometricasDropDown = uidropdown(app.Tab2);
            app.GeometricasDropDown.Items = {'Rotación', 'Escalamiento', 'Traslación'};
            app.GeometricasDropDown.Value = 'Rotación';
            app.LogicasDropDownLabel = uilabel(app.Tab2);
            app.LogicasDropDownLabel.Text = 'Logicas';
            app.LogicasDropDown = uidropdown(app.Tab2);
            app.LogicasDropDown.Items = {'AND Escalar', 'AND', 'OR Escalar', 'OR Union', 'NOT'};
            app.LogicasDropDown.Value = 'AND Escalar';
            app.LogicasCompuestasDropDownLabel = uilabel(app.Tab2);
            app.LogicasCompuestasDropDownLabel.Text = {'Logicas'; 'Compuestas'};
            app.LogicasCompuestasDropDown = uidropdown(app.Tab2);
            app.LogicasCompuestasDropDown.Items = {'NAND ', 'XOR', 'NOR', 'XNOR'};
            app.LogicasCompuestasDropDown.Value = 'NAND ';
            app.Pixel2EditFieldLabel = uilabel(app.Tab2);
            app.Pixel2EditFieldLabel.Text = 'Pixel 2';
            app.Pixel2EditField = uieditfield(app.Tab2, 'numeric');
            app.ValorConstanteLabel = uilabel(app.Tab2);
            app.ValorConstanteLabel.Text = {'Valor '; 'Constante'};
            app.ValorConstanteEditField = uieditfield(app.Tab2, 'numeric');
            app.CalcularAritmeticaButton = uibutton(app.Tab2, 'push');
            app.CalcularAritmeticaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularAritmeticaButtonPushed, true);
            app.CalcularAritmeticaButton.Text = 'Calcular';
            app.RotacinEditField = uieditfield(app.Tab2, 'numeric');
            app.RotacinEditFieldLabel = uilabel(app.Tab2);
            app.RotacinEditFieldLabel.Text = 'Rotación';
            app.EscalamientoEditFieldLabel = uilabel(app.Tab2);
            app.EscalamientoEditFieldLabel.Text = 'Escalamiento';
            app.EscalamientoEditField = uieditfield(app.Tab2, 'numeric');
            app.XEditFieldLabel = uilabel(app.Tab2);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.Tab2, 'numeric');
            app.YEditFieldLabel = uilabel(app.Tab2);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.Tab2, 'numeric');
            app.TraslacionLabel = uilabel(app.Tab2);
            app.TraslacionLabel.Text = 'Traslacion';
            app.CalcularGeometricasButton = uibutton(app.Tab2, 'push');
            app.CalcularGeometricasButton.Text = 'Calcular';
            app.CalcularLogicaButton = uibutton(app.Tab2, 'push');
            app.CalcularLogicaButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularLogicaButtonPushed, true);
            app.CalcularLogicaButton.Text = 'Calcular Logica';
            app.CalcularLCButton = uibutton(app.Tab2, 'push');
            app.CalcularLCButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularLCButtonPushed, true);
            app.CalcularLCButton.Text = 'Calcular LC';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.RuidoyFIltrosLabel = uilabel(app.Tab3);
            app.RuidoyFIltrosLabel.Text = 'Ruido y FIltros';
            app.RuidoDropDownLabel = uilabel(app.Tab3);
            app.RuidoDropDownLabel.Text = 'Ruido';
            app.RuidoDropDown = uidropdown(app.Tab3);
            app.RuidoDropDown.Items = {'Sal y Pimienta', 'Gaussiano', 'Uniforme', 'Impulso'};
            app.RuidoDropDown.Value = 'Sal y Pimienta';
            app.IntensidadDropDown = uidropdown(app.Tab3);
            app.IntensidadDropDown.Items = {'Nivel 1 - Muy bajo', 'Nivel 2 - Medio', 'Nivel 3 - Alto', 'Nivel 4 - Muy Alto'};
            app.IntensidadDropDown.Value = 'Nivel 1 - Muy bajo';
            app.IntensidadDropDownLabel = uilabel(app.Tab3);
            app.IntensidadDropDownLabel.Text = 'Intensidad';
            app.AplicarRuidoButton = uibutton(app.Tab3, 'push');
            app.AplicarRuidoButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarRuidoButtonPushed, true);
            app.AplicarRuidoButton.Text = 'Aplicar Ruido';
            app.FiltroDropDownLabel = uilabel(app.Tab3);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.Tab3);
            app.FiltroDropDown.Items = {'Promedio', 'Mediana', 'Gaussiano', 'BIlaterial'};
            app.FiltroDropDown.Value = 'Promedio';
            app.AplicarFiltroButton = uibutton(app.Tab3, 'push');
            app.AplicarFiltroButton.ButtonPushedFcn = createCallbackFcn(app, @AplicarFiltroButtonPushed, true);
            app.AplicarFiltroButton.Text = 'Aplicar Filtro';
            app.CargarImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.ReiniciarEdicionButton = uibutton(app.UIFigure, 'push');
            app.ReiniciarEdicionButton.Text = 'Reiniciar Edicion';
            app.DescargarButton = uibutton(app.UIFigure, 'push');
            app.DescargarButton.Text = 'Descargar';
            app.CargarSegunaImagenButton = uibutton(app.UIFigure, 'push');
            app.CargarSegunaImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarSegunaImagenButtonPushed, true);
            app.CargarSegunaImagenButton.Text = 'Cargar Seguna Imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end